classdef interactive_solver < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        GraphAxes   matlab.ui.control.UIAxes
        SolveBtn    matlab.ui.control.Button
        StatusAxes  matlab.ui.control.UIAxes
        StatusLbl   matlab.ui.control.Label
        StopBtn     matlab.ui.control.Button
        ClearBtn    matlab.ui.control.Button
        Label_2     matlab.ui.control.Label
        X0sp        matlab.ui.control.Spinner
        Label_3     matlab.ui.control.Label
        dx0sp       matlab.ui.control.Spinner
    end

    
    properties (Access = private)
        Start_cond = [1; 1];   % Начальные условия
        t_interval = [1 5];    % Интервал рассчитываемого времени
        
        max_step = 1e-3;
        
        t_fcn = 0;
        X_fcn = 0; 
        
        PausedSolution = [];
        PausedALine = [];
        
        stop_signal = 0;
        pause_signal = 0;
    end
    
    methods (Access = private)
                
        function odesolve(app)
            app.ClearBtn.Enable = 'off';
            app.SolveBtn.Enable = 'off';
            app.StopBtn.Enable = 'on';
            
            aline = animatedline(app.GraphAxes);
            
            options = odeset('MaxStep', app.max_step, 'OutputFcn',@(t,X,flag) OutputOde(t,X,flag,aline));
            Solution = ode45(@(t,x) euler(t,x), app.t_interval, app.Start_cond, options);
            app.t_fcn = app.t_interval(1):0.01:Solution.x(end);
            app.X_fcn = deval(Solution,app.t_fcn);
         
            clearpoints(aline)
            plot(app.GraphAxes, app.t_fcn, app.X_fcn(1,:))
            
            app.ClearBtn.Enable = 'on';
            app.SolveBtn.Enable = 'on';
            app.StopBtn.Enable = 'off';
             
            function dXdt = euler(t,X)
                x  = X(1);
                dx = X(2);
                d2x = -dx/t - (100*x)/t;            
                dXdt = [X(2); d2x];
            end
        
            function status = OutputOde(t,X,flag,aline)
                switch flag
                    case []

                        addpoints(aline, t', X(1,:)')
                        
                        percent_status = floor(((t(end)-app.t_interval(1))./(app.t_interval(2)-app.t_interval(1)))*100);
                        
                        app.UpdateStatus(percent_status);
                        
                        pause(0.00001)
                        
                        if app.stop_signal==0
                            status=0;
                        else
                            status=1;
                        end
         
                end
            end
        end                
        
        function UpdateStatus(app,value)
            
            app.StatusLbl.Text = [num2str(value),'%'];
            app.StatusAxes.Children(1).Position = [0 0 value 1];
            
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function StartupUI(app)
            
            app.GraphAxes.Color = 'white';
                app.GraphAxes.Box = 'on';
                app.GraphAxes.XGrid = 'on';
                app.GraphAxes.YGrid = 'on';
                app.GraphAxes.GridColor = [0,0,0];
                hold(app.GraphAxes,'on')
                
            rectangle(app.StatusAxes, 'Position',[0 0 100 1],  ...
                    'FaceColor',[0.94,0.94,0.94], 'EdgeColor','k'); 
            rectangle(app.StatusAxes, 'Position',[0 0 0 1], ...
                    'FaceColor',[0.78, 1, 0.71], 'EdgeColor','k','Tag','CurStatus');               
            
        end

        % Button pushed function: SolveBtn
        function SolveBtnPush(app, event)
            app.stop_signal = 0;
            app.odesolve
        end

        % Button pushed function: StopBtn
        function StopBtnPush(app, event)
            app.stop_signal = 1;
            app.UpdateStatus(0);
        end

        % Button pushed function: ClearBtn
        function ClearBtnPush(app, event)
            cla(app.GraphAxes)
        end

        % Value changed function: X0sp
        function X0spChange(app, event)
            value = app.X0sp.Value;
            app.Start_cond(1) = value;
        end

        % Value changed function: dx0sp
        function dX0spChange(app, event)
            value = app.dx0sp.Value;
            app.Start_cond(2) = value;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.GraphAxes = uiaxes(app.UIFigure);
            title(app.GraphAxes, {'Решение дифференциального уравнения Эйлера'; ''})
            xlabel(app.GraphAxes, 't')
            ylabel(app.GraphAxes, 'x')
            app.GraphAxes.XLim = [1 5];
            app.GraphAxes.YLim = [-1.5 1.5];
            app.SolveBtn = uibutton(app.UIFigure, 'push');
            app.SolveBtn.ButtonPushedFcn = createCallbackFcn(app, @SolveBtnPush, true);
            app.SolveBtn.Text = 'Рассчет';
            app.StatusAxes = uiaxes(app.UIFigure);
            title(app.StatusAxes, 'Прогресс вычисления')
            app.StatusAxes.XLim = [0 100];
            app.StatusAxes.ZLim = [0 1];
            app.StatusAxes.XTick = [];
            app.StatusAxes.YTick = [];
            app.StatusAxes.ZTick = [];
            app.StatusLbl = uilabel(app.UIFigure);
            app.StatusLbl.Text = '0%';
            app.StopBtn = uibutton(app.UIFigure, 'push');
            app.StopBtn.ButtonPushedFcn = createCallbackFcn(app, @StopBtnPush, true);
            app.StopBtn.Enable = 'off';
            app.StopBtn.Text = {'Остановить рассчет'; ''};
            app.ClearBtn = uibutton(app.UIFigure, 'push');
            app.ClearBtn.ButtonPushedFcn = createCallbackFcn(app, @ClearBtnPush, true);
            app.ClearBtn.Text = 'Очистить график';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'Начальное положение';
            app.X0sp = uispinner(app.UIFigure);
            app.X0sp.Step = 0.1;
            app.X0sp.ValueChangedFcn = createCallbackFcn(app, @X0spChange, true);
            app.X0sp.Value = 1;
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = 'Начальная скорость';
            app.dx0sp = uispinner(app.UIFigure);
            app.dx0sp.Step = 0.1;
            app.dx0sp.ValueChangedFcn = createCallbackFcn(app, @dX0spChange, true);
            app.dx0sp.Value = 1;
        end
    end

    methods (Access = public)

        % Construct app
        function app = interactive_solver

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @StartupUI)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end